classdef winMonitoringPlan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        Card4_stationsOutRoute       matlab.ui.control.Label
        Card3_stationsOnRoute        matlab.ui.control.Label
        Card2_numberOfRiskStations   matlab.ui.control.Label
        Card1_numberOfStations       matlab.ui.control.Label
        UITree                       matlab.ui.container.CheckBoxTree
        config_geoAxesLabel_2        matlab.ui.control.Label
        play_ControlsTab1Grid_2      matlab.ui.container.GridLayout
        play_ControlsTab1Image_2     matlab.ui.control.Image
        play_ControlsTab1Label_2     matlab.ui.control.Label
        toolGrid                     matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        ButtonKML                    matlab.ui.control.Button
        tool_tableNRows              matlab.ui.control.Label
        jsBackDoor                   matlab.ui.control.HTML
        GravarButton                 matlab.ui.control.Image
        tool_TableVisibility         matlab.ui.control.Image
        tool_ControlPanelVisibility  matlab.ui.control.Image
        UITable                      matlab.ui.control.Table
        axesToolbarGrid              matlab.ui.container.GridLayout
        axesTool_RegionZoom          matlab.ui.control.Image
        axesTool_RestoreView         matlab.ui.control.Image
        plotPanel                    matlab.ui.container.Panel
        filter_ContextMenu           matlab.ui.container.ContextMenu
        filter_delButton             matlab.ui.container.Menu
        filter_delAllButton          matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false

        CallingApp
        General
        rootFolder
        
        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog
        
        %-----------------------------------------------------------------%
        % ESPECIFICIDADES
        %-----------------------------------------------------------------%
        % Instância da classe class.metaData contendo a organização da
        % informação lida dos arquivos de medida. 
        measData  = class.measData.empty

        % measTable é a concatenação de todas as timetables de measData,
        % uma para cada arquivo.
        measTable

        % Dados das estações do Plano Anual de RNI:
        % (pendente criar possibilidade de atualizar planilha, no módulo
        % auxApp.winConfig)
        stationTable

        % Handle do eixo e propriedade que armazena os limites automáticos
        UIAxes
        restoreView = struct('ID', {}, 'xLim', {}, 'yLim', {}, 'cLim', {})
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        % JSBACKDOOR
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource = ccTools.fcn.jsBackDoorHTMLSource();
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            if app.isDocked
                app.progressDialog = app.CallingApp.progressDialog;
            else
                app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
            end

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        'body',                           ...
                                                                                   'classAttributes', ['--tabButton-border-color: #fff;' ...
                                                                                                       '--tabContainer-border-color: #fff;']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-theme-light',                                                   ...
                                                                                   'classAttributes', ['--mw-backgroundColor-dataWidget-selected: rgb(180 222 255 / 45%); ' ...
                                                                                                       '--mw-backgroundColor-selected: rgb(180 222 255 / 45%); '            ...
                                                                                                       '--mw-backgroundColor-selectedFocus: rgb(180 222 255 / 45%);'        ...
                                                                                                       '--mw-backgroundColor-tab: #fff;']));

            sendEventToHTMLSource(app.jsBackDoor, 'htmlClassCustomization', struct('className',        '.mw-default-header-cell', ...
                                                                                   'classAttributes',  'font-size: 10px; white-space: pre-wrap; margin-bottom: 5px;'));

            ccTools.compCustomizationV2(app.jsBackDoor, app.axesToolbarGrid, 'borderBottomLeftRadius', '5px', 'borderBottomRightRadius', '5px')
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        % INICIALIZAÇÃO
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)            
            % A criação desse timer tem como objetivo garantir uma renderização 
            % mais rápida dos componentes principais da GUI, possibilitando a 
            % visualização da sua tela inicialpelo usuário. Trata-se de aspecto 
            % essencial quando o app é compilado como webapp.

            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)

                startup_Controller(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow

            % Customiza aspectos estéticos de alguns dos componentes da GUI 
            % (diretamente em JS).
            jsBackDoor_Customizations(app)

            % Define tamanho mínimo do app (não aplicável à versão webapp).
            if ~strcmp(app.CallingApp.executionMode, 'webApp') && ~app.isDocked
                appUtil.winMinSize(app.UIFigure, class.Constants.windowMinSize)
            end

            app.progressDialog.Visible = 'visible';

            startup_AppProperties(app)
            startup_GUIComponents(app)
            startup_InitialAnalysis(app)

            app.progressDialog.Visible = 'hidden';
        end

        %-----------------------------------------------------------------%
        function startup_AppProperties(app)
            if isempty(app.CallingApp.stationTable)
                app.CallingApp.stationTable = fileReader.MonitoringPlanStations(fullfile(app.rootFolder, 'DataBase', 'PA_RNI', 'Dados_PA_RNI.csv'));
            end
            app.stationTable = app.CallingApp.stationTable;
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            startup_AxesCreation(app)            
            startup_TreeBuilding(app)

            app.tool_TableVisibility.UserData = true;
        end

        %-----------------------------------------------------------------%
        function startup_AxesCreation(app)
            % Eixo geográfico: MAPA
            app.plotPanel.AutoResizeChildren = 'off';
            app.UIAxes = plot.axes.Creation(app.plotPanel, 'Geographic', {'Units',    'normalized',             ...
                                                                           'Position', [0 0 1 1 ],               ...
                                                                           'Basemap',  app.General.Plot.GeographicAxes.Basemap, ...
                                                                           'UserData', struct('CLimMode', 'auto', 'Colormap', '')});

            set(app.UIAxes.LatitudeAxis,  'TickLabels', {}, 'Color', 'none')
            set(app.UIAxes.LongitudeAxis, 'TickLabels', {}, 'Color', 'none')
            
            geolimits(app.UIAxes, 'auto')
            app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');

            plot.axes.Colormap(app.UIAxes, app.General.Plot.GeographicAxes.Colormap)
            plot.axes.Colorbar(app.UIAxes, app.General.Plot.GeographicAxes.Colorbar)

            % Legenda
            legend(app.UIAxes, 'Location', 'southwest', 'Color', [.94,.94,.94], 'EdgeColor', [.9,.9,.9], 'NumColumns', 4, 'LineWidth', .5, 'FontSize', 7.5)

            % Axes interactions:
            plot.axes.Interactivity.DefaultCreation(app.UIAxes, [dataTipInteraction, zoomInteraction, panInteraction])
        end

        %-----------------------------------------------------------------%
        function startup_TreeBuilding(app)
            if ~isempty(app.UITree.Children)
                delete(app.UITree.Children)
            end

            listOfLocations = unique({app.measData.Location});
            for ii = 1:numel(listOfLocations)
                uitreenode(app.UITree, 'Text', listOfLocations{ii});
            end

            app.UITree.CheckedNodes = app.UITree.Children(1);
        end

        %-----------------------------------------------------------------%
        function startup_InitialAnalysis(app)
            updateTable(app)
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updateTable(app)
            selectedLocations = {app.UITree.CheckedNodes.Text};
            idxFile = find(ismember({app.measData.Location}, selectedLocations));

            % Concatena as tabelas de LATITUDE, LONGITUDE E NÍVEL de cada um
            % dos arquivos cuja localidade coincide com o que foi selecionado
            % em tela. 
            listOfTables = {app.measData(idxFile).Data};            
            app.measTable = sortrows(vertcat(listOfTables{:}), 'Timestamp', 'descend');
            plot_Measures(app)

            % Identifica localidades relacionadas à monitoração sob análise.
            listOfLocations = {};
            DIST_km = app.General.Distance.MonitoringPlan/1000;

            for ii = idxFile
                % Limites de latitude e longitude relacionados à rota, acrescentando 
                % a distância máxima à estação p/ fins de cômputo de medidas válidas 
                % no entorno de uma estação.
                [maxLatitude, maxLongitude] = reckon(app.measData(ii).LatitudeLimits(2), app.measData(ii).LongitudeLimits(2), km2deg(DIST_km), 45);
                [minLatitude, minLongitude] = reckon(app.measData(ii).LatitudeLimits(1), app.measData(ii).LongitudeLimits(1), km2deg(DIST_km), 225);

                idxLogicalStation = app.stationTable.("Latitude da Estação")  >= minLatitude  & ...
                                    app.stationTable.("Latitude da Estação")  <= maxLatitude  & ...
                                    app.stationTable.("Longitude da Estação") >= minLongitude & ...
                                    app.stationTable.("Longitude da Estação") <= maxLongitude;

                if any(idxLogicalStation)
                    listOfLocations = [listOfLocations; unique(app.stationTable.("Municipio")(idxLogicalStation))];
                end
            end

            idxStations = find(ismember(app.stationTable.("Municipio"), listOfLocations));
            identifyMeasuresForEachStation(app, idxStations, DIST_km)
            app.UITable.Data = sortrows(app.stationTable(idxStations, {'Location',             ...
                                                                       'Serviço',              ...
                                                                       'N° da Estacao',        ...
                                                                       'numberOfMeasures',     ...
                                                                       'numberOfRiskMeasures', ...
                                                                       'minFieldValue',        ...
                                                                       'meanFieldValue',       ...
                                                                       'maxFieldValue',        ...
                                                                       'Justificativa'}), 'numberOfMeasures', 'descend');

            nStations         = numel(idxStations);
            nRiskStations     = sum(app.stationTable.numberOfRiskMeasures > 0);
            nStationsOnRoute  = sum(app.stationTable.numberOfMeasures > 0);
            nStationsOutRoute = nStations - nStationsOnRoute;


            app.Card1_numberOfStations.Text     = sprintf(['<p style="margin: 10 2 0 2px;"><font style="color: BLACK; font-size: 32px;">' ...
                                                           '%d</font>\nESTAÇÕES LOCALIZADAS NOS MUNICÍPIOS SOB ANÁLISE</p>'], nStations);
            app.Card2_numberOfRiskStations.Text = sprintf(['<p style="margin: 10 2 0 2px;"><font style="color: BLACK; font-size: 32px;">' ...
                                                           '%d</font>\nESTAÇÕES NO ENTORNO DE REGISTROS DE NÍVEIS ACIMA DE 14 V/m</p></p>'], nRiskStations);
            app.Card3_stationsOnRoute .Text     = sprintf(['<p style="margin: 10 2 0 2px;"><font style="color: BLACK; font-size: 32px;">' ...
                                                           '%d</font>\nESTAÇÕES INSTALADAS NO ENTORNO DA ROTA</p>'], nStationsOnRoute);
            app.Card4_stationsOutRoute.Text     = sprintf(['<p style="margin: 10 2 0 2px;"><font style="color: BLACK; font-size: 32px;">' ...
                                                           '%d</font>\nESTAÇÕES INSTALADAS FORA DA ROTA</p>'], nStationsOutRoute);
        end

        %-----------------------------------------------------------------%
        function plot_Measures(app)
            geolimits(app.UIAxes, 'auto')
            app.UIAxes.CLimMode = 'auto';

            delete(findobj(app.UIAxes.Children, 'Tag', 'Measure'))

            % Plotar os pontos no mapa com a Tag definida
            hPlot = geoscatter(app.UIAxes, app.measTable.Latitude, app.measTable.Longitude, [], app.measTable.FieldValue, 'filled', 'Tag', 'Measure');
            hPlot.DataTipTemplate.DataTipRows(3).Label  = 'Nivel';
            hPlot.DataTipTemplate.DataTipRows(3).Format = '%0.2f V/m';

            app.restoreView = struct('ID', 'app.UIAxes', 'xLim', app.UIAxes.LatitudeLimits, 'yLim', app.UIAxes.LongitudeLimits, 'cLim', 'auto');
        end
        
        %-----------------------------------------------------------------%
        function identifyMeasuresForEachStation(app, idxStations, DIST_km)
            for ii = idxStations'
                % Inicialmente, afere a distância da estação a cada uma das
                % medidas, identificando aquelas no entorno.
                stationDistance    = deg2km(distance(app.stationTable.('Latitude da Estação')(ii), app.stationTable.('Longitude da Estação')(ii), app.measTable.Latitude, app.measTable.Longitude));                
                idxLogicalMeasures = stationDistance <= DIST_km;

                if any(idxLogicalMeasures)
                    stationMeasures = app.measTable(idxLogicalMeasures, :);
                    [maxFieldValue, idxMaxFieldValue] = max(stationMeasures.FieldValue);

                    app.stationTable.numberOfMeasures(ii)     = height(stationMeasures);
                    app.stationTable.numberOfRiskMeasures(ii) = sum(stationMeasures.FieldValue > app.General.Field.MonitoringPlan);                    
                    app.stationTable.minFieldValue(ii)        = min(stationMeasures.FieldValue);
                    app.stationTable.meanFieldValue(ii)       = mean(stationMeasures.FieldValue);
                    app.stationTable.maxFieldValue(ii)        = maxFieldValue;
                    app.stationTable.maxFieldTimestamp(ii)    = stationMeasures.Timestamp(idxMaxFieldValue);
                    app.stationTable.maxFieldLatitude(ii)     = stationMeasures.Latitude(idxMaxFieldValue);
                    app.stationTable.maxFieldLongitude(ii)    = stationMeasures.Longitude(idxMaxFieldValue);        
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.CallingApp = mainapp;
            app.General    = mainapp.General;
            app.rootFolder = mainapp.rootFolder;            
            app.measData   = mainapp.measData;

            jsBackDoor_Initialization(app)

            if app.isDocked
                app.GridLayout.Padding(4) = 21;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            appBackDoor(app.CallingApp, app, 'closeFcn', 'MONITORINGPLAN')
            delete(app)
            
        end

        % Image clicked function: tool_ControlPanelVisibility, 
        % ...and 1 other component
        function tool_InteractionImageClicked(app, event)
            
            focus(app.jsBackDoor)
            
            switch event.Source
                case app.tool_ControlPanelVisibility
                    if app.GridLayout.ColumnWidth{2}
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowRight_32.png';
                        app.GridLayout.ColumnWidth(2:3) = {0,0};
                    else
                        app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
                        app.GridLayout.ColumnWidth(2:3) = {275,10};
                    end

                case app.tool_TableVisibility
                    app.tool_TableVisibility.UserData = ~app.tool_TableVisibility.UserData;
                    if app.tool_TableVisibility.UserData
                        app.UITable.Visible = 1;
                        app.GridLayout.RowHeight(4:5) = {10,'.4x'};
                    else
                        app.UITable.Visible = 0;
                        app.GridLayout.RowHeight(4:5) = {0,0};
                    end
            end

        end

        % Callback function: UITree
        function UITreeCheckedNodesChanged(app, event)
            
            selectedNodes = app.UITree.CheckedNodes;

            if ~isempty(selectedNodes)
                idxLogical_Cach = [];
            
                for ii = 1:numel(selectedNodes)
                    idxLogical  = find(strcmp(strcat(app.Data_Meas_cache.UF, " - ", app.Data_Meas_cache.Municipio), string(selectedNodes(ii).Text)));
                    idxLogical  = unique([idxLogical_Cach; idxLogical], 'stable');
                    idxLogical_Cach = idxLogical;
                end

                if ~isempty(idxLogical_Cach)
                    app.UITable.Data          = app.UTTable_Formated(idxLogical_Cach,:);
                    app.Data_Meas_cache_Select = app.Data_Meas_cache(idxLogical_Cach,:);
                else
                    app.UITable.Data          = app.UITable.Data;
                    app.Data_Meas_cache_Select = app.Data_Meas_cache;
                end
    
                % calcula o Emax das estações e os valores de Datatime e coordenadas associadas aos Emáx 
                Dist_Max_Level = app.General.Distance.ExternalRequest;
                identifyMeasuresForEachStation(app, Dist_Max_Level);

            else
                app.UITable.Data = app.UTTable_Formated;
            end     

        end

        % Button pushed function: ButtonKML
        function ButtonKMLPushed(app, event)

            % Criar arquivo KML
            arq_KML = 'mapa_earth.kml';
            sub_folder = 'DataBase\PA_RNI\';
            outputFileName = fullfile(app.rootFolder, sub_folder, arq_KML);
            %outputFileName = 'C:\P&D\AppRNI\DataBase\PA_RNI\mapa_pontos.kml';
            
            % Criar o arquivo KML
            Names = repmat("", size(app.measTable.Latitude));  % Nome vazio para cada ponto
            kmlwrite(outputFileName, app.measTable.Latitude, app.measTable.Longitude, 'Name', Names);
            %kmlwrite(outputFileName, Lat, Lon, 'Name', Names);
            
            % Mensagem de sucesso
            uialert(app.UIFigure, ['Arquivo KML criado: ' arq_KML], 'Aviso');

        end

        % Image clicked function: GravarButton
        function GravarButtonPushed(app, event)
            
            % Lendo os dados existentes do arquivo CSV
            try
                opts = detectImportOptions(app.Path_Data_PA_RNI_Out);
                opts.PreserveVariableNames = true;
                app.Data_PA_RNI_Out = readtable(app.Path_Data_PA_RNI_Out, opts);
            catch
                startupFcn(app);
            end
      
            index_out = find(~isnan(app.UITable.Data.('Emáx (V/m)')));

            for pp = 1: numel(index_out)
                Idx_Station = find(strcmp(string(app.Data_Meas_cache.('N° da Estacao')),  string(app.UITable.Data.('N° da Estacao')(index_out(pp)))));

                app.Data_PA_RNI_Out.('Data da Medição'){Idx_Station} = app.UITable.Data.('Data da Medição')(index_out(pp));
                app.Data_PA_RNI_Out.('Emáx (V/m)'){Idx_Station} = app.UITable.Data.('Emáx (V/m)')(index_out(pp));
                app.Data_PA_RNI_Out.('Latitude Emáx'){Idx_Station} = app.UITable.Data.('Latitude Emáx')(index_out(pp));
                app.Data_PA_RNI_Out.('Longitude Emáx'){Idx_Station} = app.UITable.Data.('Longitude Emáx')(index_out(pp));
                app.Data_PA_RNI_Out.('> 14 V/M')(Idx_Station) = app.UITable.Data.('> 14 V/M')(index_out(pp));
                app.Data_PA_RNI_Out.('Justificativa (apenas NV)'){Idx_Station} = app.UITable.Data.('Justificativa (apenas NV)')(index_out(pp));
                app.Data_PA_RNI_Out.('Observações importantes'){Idx_Station} = app.UITable.Data.('Observações importantes')(index_out(pp));
            end
            
            % Escrevendo os dados atualizados de volta no arquivo xlsx
            writetable(app.Data_PA_RNI_Out, app.Path_Data_PA_RNI_Out);
            uialert(app.UIFigure, 'Arquivo XLSX atualizado com sucesso!', 'Feito!','Icon','success');

        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            
            idxTable = event.InteractionInformation.DisplayRow;

            if isempty(app.Data_Meas_cache_Select)
                uialert(app.UIFigure, 'Realize o cálculo dos parâmetros de Emáx do(s) arquivo(s) RNI e após selecione com duplo click a visualização das informações da estação desejada', 'Aviso')
                return
            end

            app.HTML.HTMLSource = fcn.htmlCode_station_row_selected(app.Data_Meas_cache_Select, idxTable);

            % Obtém as coordenadas da estação selecionada pra plotar no mapAxes
            Lat_Est  = app.Data_Meas_cache_Select.('Latitude da Estação')(idxTable);
            Long_Est = app.Data_Meas_cache_Select.('Longitude da Estação')(idxTable);
     
            % Obtém as coordeandas das coordenadas onde está o ponto de Emáx
            Lat_Est_EMax  =  app.Data_Meas_cache_Select{idxTable,11};
            Long_Est_EMax =  app.Data_Meas_cache_Select{idxTable,12};
            
            % Antes de plotar, apaga os plots que não mais fazem sentido.
            plotTag = 'tableSelectionRow';
            delete(findobj(app.UIAxes.Children, 'Tag', plotTag))
            
            % Plot the point station
            EstPlot = geoscatter(app.UIAxes, Lat_Est, Long_Est, 'Marker', 'p', 'MarkerEdgeColor', 'red', 'MarkerFaceColor', 'red', 'Tag', plotTag);

            EstPlot.DataTipTemplate.DataTipRows(3) = sprintf('Estação nº: %d',app.Data_Meas_cache_Select.('N° da Estacao')(idxTable));
            EstPlot.DataTipTemplate.DataTipRows = EstPlot.DataTipTemplate.DataTipRows([1, 2, 3]);

            % Desenha o cículo de raio Dist_Emax ao redor da estação 
            drawcircle(app.UIAxes, 'Position', [Lat_Est, Long_Est], ...
                                    'Radius', (app.General.Distance.ExternalRequest/100000), ...
                                    'Color', 'green', ...
                                    'FaceSelectable', 0, ...
                                    'InteractionsAllowed', 'none', ...
                                    'EdgeAlpha', 0, ...
                                    'Tag', plotTag)

            % Plot the point EMax
            EmaxPlot = geoscatter(app.UIAxes, Lat_Est_EMax, Long_Est_EMax, app.Data_Meas_cache_Select.('Emáx (V/m)')(idxTable), 'LineWidth', 6, 'Marker','square', 'MarkerEdgeColor', 'yellow', 'Tag', plotTag);
            EmaxPlot.DataTipTemplate.DataTipRows = EstPlot.DataTipTemplate.DataTipRows([1, 2, 3]);
            Emapp.mapAxesaxPlot.DataTipTemplate.DataTipRows(3).Label = 'Nivel (Emáx)';
            EmaxPlot.DataTipTemplate.DataTipRows(3).Value = app.Data_Meas_cache_Select.('Emáx (V/m)')(idxTable);
            EmaxPlot.DataTipTemplate.DataTipRows(3).Format = '%2.2f V/m';

            % Define limtes geográficos conforme arquivos de meidições
            arclen = km2deg(2*(app.General.Distance.ExternalRequest/1000));
            [~, lim_long1] = reckon(Lat_Est,Long_Est,arclen,-90);
            [~, lim_long2] = reckon(Lat_Est,Long_Est,arclen,90);

            [lim_lat1, ~] = reckon(Lat_Est,Long_Est,arclen,180);
            [lim_lat2, ~] = reckon(Lat_Est,Long_Est,arclen,0);

            geolimits(app.UIAxes, [lim_lat1, lim_lat2], [lim_long1, lim_long2]);

            % [lat2,lon2] = reckon(lat1,lon1,arclen,az)
            % deg = km2deg(km)

            general_ControlPanelSelectionChanged(app, struct('Source', app.menu_Button4Icon))

        end

        % Image clicked function: axesTool_RegionZoom, axesTool_RestoreView
        function axesTool_InteractionImageClicked(app, event)
            
            switch event.Source
                case app.axesTool_RestoreView
                    geolimits(app.UIAxes, app.restoreView(1).xLim, app.restoreView(1).yLim)

                case app.axesTool_RegionZoom
                    plot.axes.Interactivity.GeographicRegionZoomInteraction(app.UIAxes, app.axesTool_RegionZoom)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'RNI';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.plotPanel = uipanel(app.GridLayout);
            app.axesToolbarGrid = uigridlayout(app.GridLayout);
            app.axesTool_RestoreView = uiimage(app.axesToolbarGrid);
            app.axesTool_RestoreView.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RestoreView.Tooltip = {'RestoreView'};
            app.axesTool_RestoreView.ImageSource = 'Home_18.png';
            app.axesTool_RegionZoom = uiimage(app.axesToolbarGrid);
            app.axesTool_RegionZoom.ImageClickedFcn = createCallbackFcn(app, @axesTool_InteractionImageClicked, true);
            app.axesTool_RegionZoom.Tooltip = {'RegionZoom'};
            app.axesTool_RegionZoom.ImageSource = 'ZoomRegion_20.png';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Localidade'; 'Serviço'; 'Estação'; 'Qtd.|Medidas'; 'Qtd.|> 14 V/m'; 'Emin|(V/m)'; 'Emean|(V/m)'; 'Emax|(V/m)'; 'Justificativa'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true true true true true false];
            app.UITable.ColumnEditable = [false false false false false false false false true];
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UITable.Multiselect = 'off';
            app.toolGrid = uigridlayout(app.GridLayout);
            app.tool_ControlPanelVisibility = uiimage(app.toolGrid);
            app.tool_ControlPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_ControlPanelVisibility.ImageSource = 'ArrowLeft_32.png';
            app.tool_TableVisibility = uiimage(app.toolGrid);
            app.tool_TableVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_InteractionImageClicked, true);
            app.tool_TableVisibility.Tooltip = {'Visibilidade da tabela'};
            app.tool_TableVisibility.ImageSource = 'View_16.png';
            app.GravarButton = uiimage(app.toolGrid);
            app.GravarButton.ImageClickedFcn = createCallbackFcn(app, @GravarButtonPushed, true);
            app.GravarButton.ImageSource = 'Export_16.png';
            app.jsBackDoor = uihtml(app.toolGrid);
            app.tool_tableNRows = uilabel(app.toolGrid);
            app.tool_tableNRows.Text = {'Em análise estações instaladas nas localidades "Salvador/BA" e "Ilhéus/BA"'; '25 de 1523 registros'};
            app.ButtonKML = uibutton(app.toolGrid, 'push');
            app.ButtonKML.ButtonPushedFcn = createCallbackFcn(app, @ButtonKMLPushed, true);
            app.ButtonKML.Icon = 'Instr_Global.png';
            app.ButtonKML.Text = '';
            app.Image = uiimage(app.toolGrid);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'Filter_18.png';
            app.play_ControlsTab1Grid_2 = uigridlayout(app.GridLayout);
            app.play_ControlsTab1Grid_2.Tag = 'COLORLOCKED';
            app.play_ControlsTab1Label_2 = uilabel(app.play_ControlsTab1Grid_2);
            app.play_ControlsTab1Label_2.Text = 'MEDIDAS';
            app.play_ControlsTab1Image_2 = uiimage(app.play_ControlsTab1Grid_2);
            app.play_ControlsTab1Image_2.ImageSource = 'Playback_32.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.config_geoAxesLabel_2 = uilabel(app.GridLayout2);
            app.config_geoAxesLabel_2.Text = 'LOCALIDADES';
            app.UITree = uitree(app.GridLayout2, 'checkbox');
            % Assign Checked Nodes
            app.UITree.CheckedNodesChangedFcn = createCallbackFcn(app, @UITreeCheckedNodesChanged, true);
            app.Card1_numberOfStations = uilabel(app.GridLayout2);
            app.Card1_numberOfStations.Interpreter = 'html';
            app.Card1_numberOfStations.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: BLACK; font-size: 32px;">100</font>'; 'ESTAÇÕES LOCALIZADAS NOS MUNICÍPIOS SOB ANÁLISE</p>'};
            app.Card2_numberOfRiskStations = uilabel(app.GridLayout2);
            app.Card2_numberOfRiskStations.Interpreter = 'html';
            app.Card2_numberOfRiskStations.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: #a2142f; font-size: 32px;">3</font>'; 'ESTAÇÕES NO ENTORNO DE REGISTROS DE NÍVEIS ACIMA DE 14 V/m</p>'};
            app.Card3_stationsOnRoute = uilabel(app.GridLayout2);
            app.Card3_stationsOnRoute.Interpreter = 'html';
            app.Card3_stationsOnRoute.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: black; font-size: 32px;">78</font>'; 'ESTAÇÕES INSTALADAS NO ENTORNO DA ROTA</p>'};
            app.Card4_stationsOutRoute = uilabel(app.GridLayout2);
            app.Card4_stationsOutRoute.Interpreter = 'html';
            app.Card4_stationsOutRoute.Text = {'<p style="margin: 10 2 0 2px;"><font style="color: #a2142f; font-size: 32px;">22</font>'; 'ESTAÇÕES INSTALADAS FORA DA ROTA</p>'};
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.Text = 'Excluir todos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMonitoringPlan(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end