classdef AppRNI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Button                          matlab.ui.control.Button
        uipanel                         matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        LocalidadesTab                  matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        CalcMaisProx                    matlab.ui.control.Button
        TreeLocalRNI                    matlab.ui.container.CheckBoxTree
        ImageOpenFile                   matlab.ui.control.Image
        EscolhaosarquivosdasmedidasLabel  matlab.ui.control.Label
        LocaisderealizaesdasmediesdeRNILabel  matlab.ui.control.Label
        CalculadoraTab                  matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SelecioneosGRUOsdesejadosLabel  matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        DistEditField                   matlab.ui.control.NumericEditField
        DistnciamEditFieldLabel         matlab.ui.control.Label
        MaioresnveisButton              matlab.ui.control.Button
        SelecioneasLocalidadesdesejadasLabel  matlab.ui.control.Label
        ListBox_GR_UO                   matlab.ui.control.ListBox
        CadastroTab                     matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        ExibirButton                    matlab.ui.control.Button
        TabelaCheckBox                  matlab.ui.control.CheckBox
        MapaCheckBox                    matlab.ui.control.CheckBox
        HTML                            matlab.ui.control.HTML
        ListaPARNILabel                 matlab.ui.control.Label
        EditFieldRow                    matlab.ui.control.NumericEditField
    end

   
    properties (Access = private)
        Data_PA_RNI; % Tabela das estações a serem fiscalizadas do Plano 
        % Anual do Radiação Não Inonizantes (PA_RNI)
        Data_Meas_Probes; %Dados das medições obtidos pelas sondas: NARDA e
        % MONITEM
        % Plot_Mapa;
        mapAxes; % Eixo do mapa
        Data_struct = []; %Estrutura dos Metadados
        metaData = struct('Filename', [], 'Measures', [], 'Sensor', [], 'Data', [], 'LatitudeLimits', [], 'LongitudeLimits', []);
    end 
    
    methods (Access = private)
        
        function Layout_Map = Map_Config(app)

            app.mapAxes.LatitudeLabel.String='';
            app.mapAxes.LongitudeLabel.String='';
            app.mapAxes.FontSize = 9;
            
            % Ajustar a posição do mapaxes para minimizar margens
            app.mapAxes.Units = 'normalized';
            app.mapAxes.Position = [0 0 1 1]; % Ocupar todo o espaço do painel
            app.mapAxes.OuterPosition = [0 0 1 1]; % Maximizar o uso de espaço

            % Remover etiquetas de latitude e longitude
            app.mapAxes.LatitudeLabel.Visible = 'off';
            app.mapAxes.LongitudeLabel.Visible = 'off';

            app.mapAxes.Padding = 'tight'; % Remover qualquer preenchimento
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %Centraliza a Tela do AppRNI ao abrir o aplicativo
            movegui(app.UIFigure, 'center');

            % Inicializa a figura maximizada
            app.UIFigure.WindowState = 'maximized';
            
            % Insere texto no app.HTML
            app.HTML.HTMLSource = '<!DOCTYPE html><html lang="en"><head><meta charset="UTF-8"><title>Modificar Tabela</title><style>#infoText {text-align: center; font-weight: bold; color: blue;}</style></head><body><p id="infoText">Para visualização das informações da estação monitorada clique em uma das linhas da tabela ao lado:</p></body></html>';
            
            %Insere o título no cabeçário da tabela dos dados do PA_RNI 
            app.ListaPARNILabel.Text = sprintf('Estações do Plano de Fiscalização de RNI do ano de %d',year(datetime('now')));
            
            %Realiza a leitura do arquivo csv do PA_RNI e cria a UITable
            app.Data_PA_RNI  = fcn.ReadFile_PA_RNI(app);
            app.UITable.Data = app.Data_PA_RNI;
            figure(app.UIFigure);

            app.UITable.ColumnEditable = [false false false false false false false false false false false false false false true true false];

            % Defini o nome e a largura das colunas da `UITable`
            app.UITable.ColumnName  = class.Constants.GUIColumns;
            % app.UITable.ColumnWidth = class.Constants.GuiColumnWidth;

            % Defini a area inical ocupada pelo uipanel no Grid
            % app.uipanel.Layout.Row    =  [2, 13];
            % app.uipanel.Layout.Column =  [2, 3];
            % Defini a area inical ocupada pela UITable no Grid
            % app.UITable.Layout.Row    =  [14, 19];
            % app.UITable.Layout.Column =  [2, 3];

            % Cria o mapa no app.uipanel com eixo em app.mapAxes
            app.mapAxes = geoaxes(app.uipanel);
                
            % Inseri os nomes da Ruas, stradas e Avenidas no mapa gerado 
            % app.mapAxes
            geobasemap(app.mapAxes, 'streets');
            
            % Define limtes geográficos com ênfase no mapa do Brasil
            geolimits(app.mapAxes, [-50, 35], [-90, 15]);
            
            Map_Config(app);
                       
        end

        % Value changed function: ListBox_GR_UO
        function ListBox_GR_UOValueChanged(app, event)
           
            %Armazena em ER_UO as informação as UR da tabela do PA_RNI  
            ER_UO = app.Data_PA_RNI(:,2);
            
            %Busca na Base de dados do PA_RNI as localidades da Unidade 
            %Operacional selecionada 
            Index_Local      = find(strcmp(string(table2array(ER_UO)), string(app.ListBox_GR_UO.Value)));
            app.UITable.Data =  app.Data_PA_RNI(Index_Local,:);
            Localidades      = unique(app.Data_PA_RNI(Index_Local,4)); 

            delete(app.Tree.Children);
            
            %Escreve na app.Tree as informações relacionadas a localidade
            %correpondente a Unidade Regional selecionada 
            for ii = 1:numel(Localidades)
                uitreenode(app.Tree,"Text",string(table2array(Localidades(ii,1))));
            end
            expand(app.Tree)

        end

        % Button pushed function: MaioresnveisButton
        function MaioresnveisButtonPushed(app, event)
      
            Dist_Max_Level = app.DistEditField.Value; 
            try
                Lat_Meas_Probes  = app.Data_Meas_Probes{:,1};
                Long_Meas_Probes = app.Data_Meas_Probes{:,2};
            catch
                uialert(app.UIFigure, ['Não foram encontrados arquivos referentes as medições! Insira os arquivos das medições e tente no' ...
                    'vamente!'], 'Go!','Icon','success')
                return
            end

            % Distancia máxima das estação 200 metros
            dist_EMax = (Dist_Max_Level/111.12)/1000;

            % Criar uma matriz de strings de Vazios e inseri novas colunas
            % na UITable
            matrix_nan       = strings(height(app.UITable.Data), numel(class.Constants.GUINewColumns));
            app.UITable.Data = [app.UITable.Data array2table(matrix_nan)];

            format long;

            for jj=1:height(app.UITable.Data)

                %Inicia os valores das variáveis
                % E_Max_Level   = 0;
                % Lat_Max_Dist  = 0;
                % Long_Max_Dist = 0;
                % Data_Med_Dist = 0;
                % Mor_14Vm_Dist = 0;

                Lat_Estacao   = -1*(str2double(app.UITable.Data{jj,8}));
                Long_Estacao  = -1*(str2double(app.UITable.Data{jj,9}));
            
                Lat_Max  = Lat_Estacao + dist_EMax;
                Lat_Min  = Lat_Estacao - dist_EMax;
                Long_Max = Long_Estacao + dist_EMax;
                Long_Min = Long_Estacao - dist_EMax;
                
                %Filtra de app.Data_Meas_Probes apenas os dados de medidas
                %no raio de distância máxima da estação
                filteredT = app.Data_Meas_Probes((Lat_Meas_Probes >= Lat_Min & Lat_Meas_Probes <= Lat_Max) & (Long_Meas_Probes >= Long_Min & Long_Meas_Probes <= Long_Max), :);
                [~, maxIndex] =  max(filteredT{:,3});
                
                if ~isempty(maxIndex)
                    Lat_filteredT     = filteredT{:,1};                    
                    Long_filteredT    = filteredT{:,2};
                    E_Level_filteredT = filteredT{:,3};

                    % dist = distance(Lat_Estacao,Long_Estacao,Lat_Ft_Dec(kk),Long_Ft_Dec(kk))* (pi/180) * 6371*1000;
                    dist = distance(Lat_Estacao, Long_Estacao, Lat_filteredT, Long_filteredT)* (pi/180) * 6371*1000;
                    Dist_Max_index = find(dist <= Dist_Max_Level);
                    Dist_Max_fT    = filteredT(Dist_Max_index, :);

                    Data_Med_Max   = Dist_Max_fT.DataTime_Probes;
                    Lat_Dist_Max   = Dist_Max_fT{:,1};                    
                    Long_Dist_Max  = Dist_Max_fT{:,2};
                    E_Dist_Level   = Dist_Max_fT{:,3};
                    
                    [~, maxIndex]  =  max(E_Dist_Level);

                    E_Level_Max   = E_Dist_Level(maxIndex);
                    Lat_Max_Calc  = -1*(Lat_Dist_Max(maxIndex));
                    Long_Max_Calc = -1*(Long_Dist_Max(maxIndex));
                    Data_Med_Calc = Data_Med_Max(maxIndex);
                    ContMaior14Vm = sum(E_Level_Max >= Dist_Max_Level);

                    % Inseri os valores calculados na Tabela
                    app.UITable.Data{jj, 11} = cellstr(num2str(E_Level_Max));
                    app.UITable.Data{jj, 12} = cellstr(num2str(Lat_Max_Calc, '%.8f'));
                    app.UITable.Data{jj, 13} = cellstr(num2str(Long_Max_Calc, '%.8f'));
                    app.UITable.Data(jj, 14) = cellstr(num2str(ContMaior14Vm));
                    app.UITable.Data{jj, 10} = cellstr(num2str(string(Data_Med_Calc)));                        
                    % app.UITable.Data{jj, 17} = cellstr(num2str(dist_Max_Value));               
                end               
            end
            app.UITable.ColumnName = class.Constants.GUIColumnsAll;
            % visibleData = app.UITable.Data(:, [3, 4, 7, 8, 9, 10, 11, 12, 13, 14, 15, 16]);
            % app.UITable.Data = visibleData;
            % app.UITable.ColumnEditable = [false false false false false false false false false false true true];
            uialert(app.UIFigure, 'Cálculos dos Campos elétricos máximos (EMax) realizados com sucesso!', 'Tudo certo!','Icon','success')
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            selectedNodes = app.Tree.CheckedNodes;
            
            % app.UITable.ColumnWidth = class.Constants.GuiColumnWidth;
            
            GR_UO_Select = string(app.ListBox_GR_UO.Value);
            idxLogical_GR_UO = find(strcmp(string(table2array(app.Data_PA_RNI(:,2))), GR_UO_Select));
            py = app.Data_PA_RNI(idxLogical_GR_UO,:);
            idxLogical_Cach = [];

            for pp = 1:numel(selectedNodes)
                idxLogical  = find(strcmp(string(table2array(py(:,4))), string(selectedNodes(pp).Text)));
                idxLogical  = unique([idxLogical_Cach; idxLogical], 'stable');
                idxLogical_Cach = idxLogical;
            end
            if ~isempty(idxLogical_Cach)
                app.UITable.Data = py(idxLogical,:);
            else
                app.UITable.Data = py;
            end
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            try
                app.TabGroup.SelectedTab = app.CadastroTab;
                displayRow = event.InteractionInformation.DisplayRow;
                app.EditFieldRow.Value = displayRow;
                
                htmlContent = sprintf([ ...
                    '<!DOCTYPE html><html lang="en"><head>'...
                    '<meta charset="UTF-8">'...
                    '<meta name="viewport" content="width=device-width, initial-scale=1.0">'...
                    '<title>Modificar Tabela</title>'...
                    '<style>'...  
                    'p.infoText { width: 100%%; text-align: left; font-weight: bold; color: red; margin: 10px 0; font-family: Times New Roman, sans-serif; font-size: 16px; }'... % Centraliza o texto, negrito, vermelho, Arial 12px
                    'table { width: 100%%; border-collapse: collapse; margin-bottom: 18px; }'...
                    'td { color: blue; font-weight: bold; border: 1px solid black; padding: 6px; text-align: left; }'...
                    'td.greenText { color: green; }'... % Classe para texto verde
                    '</style>'...
                    '</head><body>'...
                    '<p class="infoText">Dados da estação do Plano anual do RNI:</p>'...
                    '<table>'...
                    '<tr><td class="greenText">Estação N º: </td><td>%s</td></tr>'...  % Linha modificada
                    '<tr><td class="greenText">Município/UF: </td><td>%s</td></tr>'...
                    '<tr><td class="greenText">Serviço: </td><td>%s</td></tr>'...
                    '<tr><td class="greenText">Latitude: </td><td>%s</td></tr>'...
                    '<tr><td class="greenText">Longitude: </td><td>%s</td></tr>'...
                    '</table>'...
                    '<p class="infoText">Dados do monitoramento:</p>'...
                    '<table>'...
                    '<tr><td class="greenText">Data da medição: </td><td>%s</td></tr>'...
                    '<tr><td class="greenText">Campo maior V/m: </td><td>%s</td></tr>'...
                    '<tr><td class="greenText">Latitude Maior V/m: </td><td>%s</td></tr>'...
                    '<tr><td class="greenText">Longitude maior V/m: </td><td>%s</td></tr>'...
                    '<tr><td class="greenText">Medidas > 14 V/M: </td><td>%s</td></tr>'...
                    '<tr><td class="greenText">Justificativa de NV: </td><td>%s</td></tr>'...
                    '<tr><td class="greenText">Observações: </td><td>%s</td></tr>'...
                    '</table>'...
                    '</body></html>'], ...
                    string(table2array(app.UITable.Data(displayRow,7))), ...
                    strcat(string(table2array(app.UITable.Data(displayRow,4))),"/",string(table2array(app.UITable.Data(displayRow,3)))), ...
                    string(table2array(app.UITable.Data(displayRow,5))), ...
                    string(table2array(app.UITable.Data(displayRow,8))), ...
                    string(table2array(app.UITable.Data(displayRow,9))), ...
                    string(table2array(app.UITable.Data(displayRow,10))), ...
                    string(table2array(app.UITable.Data(displayRow,11))), ...
                    string(table2array(app.UITable.Data(displayRow,12))), ...
                    string(table2array(app.UITable.Data(displayRow,13))), ...
                    string(table2array(app.UITable.Data(displayRow,14))), ...
                    string(table2array(app.UITable.Data(displayRow,15))), ...
                    string(table2array(app.UITable.Data(displayRow,16))));
                    % string(table2array(app.UITable.Data(displayRow,17))));
                
                    % Use uma string ou char array ao invés de cell array
                    app.HTML.HTMLSource = htmlContent;

                    Latitudes_Meas  = -1*(app.Data_Meas_Probes{:,1});
                    Longitudes_Meas = -1*(app.Data_Meas_Probes{:,2});

                    %Plota o Mapa com o raio máximo ao redor da estação selecionada
                    Lat_Est  =  str2double(string(table2array(app.UITable.Data(app.EditFieldRow.Value,8))));
                    Long_Est =  str2double(string(table2array(app.UITable.Data(app.EditFieldRow.Value,9))));
             
                    %Plota o ponto de máximo EMax
                    Lat_Est_EMax  =  str2double(string(table2array(app.UITable.Data(app.EditFieldRow.Value,12))));
                    Long_Est_EMax =  str2double(string(table2array(app.UITable.Data(app.EditFieldRow.Value,13))));

                    % geoscatter(app.mapAxes, lat,lon,sizes,colors, "filled")
                    % Hold the current plot to add new points without erasing the existing ones
                    hold(app.mapAxes, 'on');

                    % Antes de ploatar, a gente apaga os plots que não mais
                    % fazem sentido.
                    plotTag = 'tableSelectionRow';
                    delete(findobj(app.mapAxes.Children, 'Tag', plotTag))
                    
                    % Plot the points Meas
                    geoscatter(app.mapAxes, Latitudes_Meas, Longitudes_Meas, "filled", 'LineWidth', 1, 'Tag', plotTag);
                    
                    % Plot the point station
                    geoscatter(app.mapAxes, Lat_Est, Long_Est, 'LineWidth', 4, 'Color', 'red', 'Tag', plotTag);

                     % Plot the point EMax
                    geoscatter(app.mapAxes, Lat_Est_EMax, Long_Est_EMax, 'LineWidth', 4, 'Color', 'yellow', 'Tag', plotTag);
                    
                    % Define limtes geográficos conforme arquivos de meidições
                    geolimits(app.mapAxes, [Lat_Est-0.02, Lat_Est+0.02], [Long_Est-0.02, Long_Est+0.02]);

                    % Optionally, release the hold state if you want to allow future plots to overwrite
                    hold(app.mapAxes, 'off');
                    
                    drawcircle(app.mapAxes, 'Position', [Lat_Est, Long_Est], ...
                                            'Radius', 0.002, ...
                                            'Color', 'red', ...
                                            'FaceSelectable', 0, ...
                                            'InteractionsAllowed', 'none', ...
                                            'EdgeAlpha', 0, ...
                                            'Tag', plotTag)
                catch
                end 
            
        end

        % Image clicked function: ImageOpenFile
        function ImageOpenFileClicked2(app, event)
            % Armazena as informações de latitude e longitude das estações 
            St_RNI_Lat  = str2double(table2array(app.Data_PA_RNI(:,8)));
            St_RNI_Long = str2double(table2array(app.Data_PA_RNI(:,9)));

            % Abre o diretório dos arquivos das medições das sondas
            initialPath = 'C:\P&D\AppRNI\DataBase\Meas_Sondas';
            
            % Abrir a caixa de diálogo para selecionar múltiplos arquivos
            [filenames, pathname] = uigetfile({'*.txt';'*.csv';'*.mat';'*.*'}, ...
                                              'Selecione arquivos', initialPath, ...
                                              'MultiSelect', 'on');
            %Mantém a Tela principal (figure) em primeiro plano
            figure(app.UIFigure);
            
            % Verifica se algum arquivo foi selecionado
            if isequal(filenames, 0)
                disp('Nenhum arquivo selecionado.');
            else
                % Se apenas um arquivo foi selecionado, filenames será uma string
                % Se múltiplos arquivos foram selecionados, filenames será um cell array
                File_Sondas = [];
                if ischar(filenames)
                    % Apenas um arquivo selecionado
                    File_Sondas = fullfile(pathname, filenames);
                    % Extrai do arquivo a informação sobre o tipo de sonda
                    % que gerou o arquivo de medição
                    Type_Meas_Probes = fcn.TypeMeasProbe(app, File_Sondas);
                    %Lê os dados do arquivo de medição para DataRows
                    DataRows = fcn.ReadFile_Meas_Probes(app, Type_Meas_Probes, File_Sondas, 1, 1);
                    % Armazena os dados de medição do arquivo em 
                    % app.Data_Meas_Probes
                    app.Data_Meas_Probes = DataRows{4};
                    app.metaData = struct('Filename', DataRows{1}, 'Measures', DataRows{2}, 'Sensor', DataRows{3}, 'Data', {DataRows{4}}, 'LatitudeLimits', DataRows(5:6), 'LongitudeLimits', DataRows(7:8));
                    app.Data_struct{1} = app.metaData;
                else
                    % Múltiplos arquivos selecionados
                    for ii = 1:length(filenames)
                        File_Sondas{ii} = fullfile(pathname, filenames{ii});
                    end
                    app.Data_Meas_Probes = [];

                    % fcn.Loop_ReadFiles(app);
                    for pp = 1:numel(File_Sondas)
                         % Extrai do arquivo a informação sobre o tipo de sonda
                         % que gerou o arquivo de medição
                         Type_Meas_Probes = fcn.TypeMeasProbe(app, File_Sondas{pp});
                         %Lê os dados do arquivo de medição para DataRows
                         DataRows = fcn.ReadFile_Meas_Probes(app, Type_Meas_Probes, File_Sondas{pp}, pp, numel(File_Sondas));
                         % Armazenar os dados de medições dos arquivos em 
                         app.Data_Meas_Probes = [app.Data_Meas_Probes; DataRows{4}];
                         app.metaData = struct('Filename', DataRows{1}, 'Measures', DataRows{2}, 'Sensor', DataRows{3}, 'Data', {DataRows{4}}, 'LatitudeLimits', DataRows(5:6), 'LongitudeLimits', DataRows(7:8));
                         app.Data_struct = [app.Data_struct; app.metaData];
                    end
                end
                LocalMaisProximo = [];
                Lat_Files  = [];
                Long_Files = [];

                for jj = 1:height(app.Data_struct)
                    
                    % Definir o número alvo
                    LatitudeLimSt = -1*(table2array(app.Data_struct(jj,:).LatitudeLimits));
                    LongitudeLimSt = -1*(table2array(app.Data_struct(jj,:).LongitudeLimits));

                    Lat_Files  = [Lat_Files; -1*(table2array(app.Data_struct(jj,1).LatitudeLimits)); -1*(table2array(app.Data_struct(jj,2).LatitudeLimits))];
                    Long_Files = [Long_Files; -1*(table2array(app.Data_struct(jj,1).LongitudeLimits)); -1*(table2array(app.Data_struct(jj,2).LongitudeLimits))];

                    dist = distance(LatitudeLimSt, LongitudeLimSt, St_RNI_Lat, St_RNI_Long);
                    
                    % Calcular a diferença entre cada elemento do vetor e o número alvo
                    [~, indice] = min(abs(dist));
                    
                    % Encontrar o valor mais próximo
                    LocalMaisProximo = [LocalMaisProximo; strcat(table2array(app.Data_PA_RNI(indice,4)), "/", table2array(app.Data_PA_RNI(indice,3)))];
                end 
    
                delete(app.TreeLocalRNI.Children);
                
                %Escreve na app.Tree as informações relacionadas a localidade
                %correpondente a Unidade Regional selecionada 
                for ii = 1:numel(LocalMaisProximo)
                    uitreenode(app.TreeLocalRNI,"Text",string(LocalMaisProximo(ii,1)));
                end
                expand(app.TreeLocalRNI)

                % geoscatter(app.mapAxes, lat,lon,sizes,colors, "filled")
                % Hold the current plot to add new points without erasing the existing ones
                hold(app.mapAxes, 'on');

                % Define limtes geográficos conforme arquivos de meidições
                geolimits(app.mapAxes, [min(Lat_Files)-1, max(Lat_Files)+3], [min(Long_Files)-1, max(Long_Files)+1]);

                % Plot the points Meas
                geoscatter(app.mapAxes, -1*(app.Data_Meas_Probes{:,1}), -1*(app.Data_Meas_Probes{:,2}), [], 'green', 'filled', 'LineWidth', 1, 'Tag', 'Route');
            
                % Optionally, release the hold state if you want to allow future plots to overwrite
                hold(app.mapAxes, 'off');
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            indices = event.Indices;
            newData = event.NewData;

            % Get the edited cell's indices
            row = event.Indices(1);
            column = event.Indices(2);
            
            % Get the new value entered by the user
            newValue = event.NewData;
            
            % Display the new value (for debugging or other purposes)
            disp(['Row: ', num2str(row), ', Column: ', num2str(column), ', New Value: ', num2str(newValue)]);
            
            % Update the table data with the new value
            app.UITable.Data{row, column} = newValue;
            
        end

        % Button pushed function: ExibirButton
        function ExibirButtonPushed(app, event)
            if app.TabelaCheckBox.Value && app.MapaCheckBox.Value
                app.UITable.Visible = "on";
                app.uipanel.Visible = "on";
                % Defini a area inical ocupada pelo uipanel no Grid
                app.uipanel.Layout.Row =  [2, 13];
                app.uipanel.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo table
                app.UITable.Layout.Row =  [14, 19];
                app.UITable.Layout.Column =  [2, 3];
            elseif app.TabelaCheckBox.Value
                % Defini a area inical ocupada pelo table
                app.UITable.Layout.Row =  [2, 19];
                app.UITable.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo uipanel no Grid
                app.uipanel.Visible = "off";
                app.UITable.Visible = "on";
            elseif app.MapaCheckBox.Value
                % Defini a area inical ocupada pelo uipanel no Grid
                app.uipanel.Layout.Row =  [2, 19];
                app.uipanel.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UITable.Visible = "off";
                app.uipanel.Visible = "on";
            else
                app.UITable.Visible = "on";
                app.uipanel.Visible = "on";
                % Defini a area inical ocupada pelo uipanel no Grid
                app.uipanel.Layout.Row =  [2, 13];
                app.uipanel.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo table
                app.UITable.Layout.Row =  [14, 19];
                app.UITable.Layout.Column =  [2, 3];
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            d = drawrectangle(app.mapAxes);
            geolimits(app.mapAxes, [d.Position(1), d.Position(1)+d.Position(3)], [d.Position(2), d.Position(2)+d.Position(4)])
            delete(d)

        end

        % Button pushed function: CalcMaisProx
        function CalcMaisProxButtonPushed(app, event)
             Dist_Max_Level = app.DistEditField.Value; 
            try
                Lat_Meas_Probes  = app.Data_Meas_Probes{:,1};
                Long_Meas_Probes = app.Data_Meas_Probes{:,2};
            catch
                uialert(app.UIFigure, ['Não foram encontrados arquivos referentes as medições! Insira os arquivos das medições e tente no' ...
                    'vamente!'], 'Go!','Icon','success')
                return
            end

            % Distancia máxima das estação 200 metros
            dist_EMax = (Dist_Max_Level/111.12)/1000;

            % Criar uma matriz de strings de Vazios e inseri novas colunas
            % na UITable
            matrix_nan       = strings(height(app.UITable.Data), numel(class.Constants.GUINewColumns));
            app.UITable.Data = [app.UITable.Data array2table(matrix_nan)];

            format long;

            for jj=1:height(app.UITable.Data)

                Lat_Estacao   = -1*(str2double(app.UITable.Data{jj,8}));
                Long_Estacao  = -1*(str2double(app.UITable.Data{jj,9}));
            
                Lat_Max  = Lat_Estacao + dist_EMax;
                Lat_Min  = Lat_Estacao - dist_EMax;
                Long_Max = Long_Estacao + dist_EMax;
                Long_Min = Long_Estacao - dist_EMax;
                
                %Filtra de app.Data_Meas_Probes apenas os dados de medidas
                %no raio de distância máxima da estação
                filteredT = app.Data_Meas_Probes((Lat_Meas_Probes >= Lat_Min & Lat_Meas_Probes <= Lat_Max) & (Long_Meas_Probes >= Long_Min & Long_Meas_Probes <= Long_Max), :);
                [~, maxIndex] =  max(filteredT{:,3});
                
                if ~isempty(maxIndex)
                    Lat_filteredT     = filteredT{:,1};                    
                    Long_filteredT    = filteredT{:,2};
                    E_Level_filteredT = filteredT{:,3};

                    % dist = distance(Lat_Estacao,Long_Estacao,Lat_Ft_Dec(kk),Long_Ft_Dec(kk))* (pi/180) * 6371*1000;
                    dist = distance(Lat_Estacao, Long_Estacao, Lat_filteredT, Long_filteredT)* (pi/180) * 6371*1000;
                    Dist_Max_index = find(dist <= Dist_Max_Level);
                    Dist_Max_fT    = filteredT(Dist_Max_index, :);

                    Data_Med_Max   = Dist_Max_fT.DataTime_Probes;
                    Lat_Dist_Max   = Dist_Max_fT{:,1};                    
                    Long_Dist_Max  = Dist_Max_fT{:,2};
                    E_Dist_Level   = Dist_Max_fT{:,3};
                    
                    [~, maxIndex]  =  max(E_Dist_Level);

                    E_Level_Max   = E_Dist_Level(maxIndex);
                    Lat_Max_Calc  = -1*(Lat_Dist_Max(maxIndex));
                    Long_Max_Calc = -1*(Long_Dist_Max(maxIndex));
                    Data_Med_Calc = Data_Med_Max(maxIndex);
                    ContMaior14Vm = sum(E_Level_Max >= Dist_Max_Level);

                    % Inseri os valores calculados na Tabela
                    app.UITable.Data{jj, 11} = cellstr(num2str(E_Level_Max));
                    app.UITable.Data{jj, 12} = cellstr(num2str(Lat_Max_Calc, '%.8f'));
                    app.UITable.Data{jj, 13} = cellstr(num2str(Long_Max_Calc, '%.8f'));
                    app.UITable.Data(jj, 14) = cellstr(num2str(ContMaior14Vm));
                    app.UITable.Data{jj, 10} = cellstr(num2str(string(Data_Med_Calc)));                        
                    % app.UITable.Data{jj, 17} = cellstr(num2str(dist_Max_Value));               
                end               
            end
            app.UITable.ColumnName = class.Constants.GUIColumnsAll;
            % visibleData = app.UITable.Data(:, [3, 4, 7, 8, 9, 10, 11, 12, 13, 14, 15, 16]);
            % app.UITable.Data = visibleData;
            % app.UITable.ColumnEditable = [false false false false false false false false false false true true];
            uialert(app.UIFigure, 'Cálculos dos Campos elétricos máximos (EMax) realizados com sucesso!', 'Tudo certo!','Icon','success')
        end

        % Selection changed function: TreeLocalRNI
        function TreeLocalRNISelectionChanged(app, event)
            
            selectedNodes = app.TreeLocalRNI.CheckedNodes;

            app.UITable.Data = app.Data_PA_RNI;
            
            idxLogical_Cach = [];

            for pp = 1:numel(selectedNodes)
                idxLogical  = find(strcmp(strcat(table2array(app.Data_PA_RNI(:,4)), "/", table2array(app.Data_PA_RNI(:,3))), string(selectedNodes(pp).Text)));
                idxLogical  = unique([idxLogical_Cach; idxLogical], 'stable');
                idxLogical_Cach = idxLogical;
            end
            if ~isempty(idxLogical_Cach)
                app.UITable.Data = app.Data_PA_RNI(idxLogical,:);
            else
                app.UITable.Data = app.Data_PA_RNI;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EditFieldRow = uieditfield(app.GridLayout, 'numeric');
            app.ListaPARNILabel = uilabel(app.GridLayout);
            app.ListaPARNILabel.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.LocalidadesTab = uitab(app.TabGroup);
            app.LocalidadesTab.Title = 'Localidades';
            app.GridLayout6 = uigridlayout(app.LocalidadesTab);
            app.LocaisderealizaesdasmediesdeRNILabel = uilabel(app.GridLayout6);
            app.LocaisderealizaesdasmediesdeRNILabel.Text = 'Locais de realizações das medições de RNI:';
            app.EscolhaosarquivosdasmedidasLabel = uilabel(app.GridLayout6);
            app.EscolhaosarquivosdasmedidasLabel.Text = 'Escolha o(s) arquivo(s) da(s) medida(s):';
            app.ImageOpenFile = uiimage(app.GridLayout6);
            app.ImageOpenFile.ImageClickedFcn = createCallbackFcn(app, @ImageOpenFileClicked2, true);
            app.ImageOpenFile.ImageSource = fullfile(pathToMLAPP, 'files.png');
            app.TreeLocalRNI = uitree(app.GridLayout6, 'checkbox');
            app.TreeLocalRNI.SelectionChangedFcn = createCallbackFcn(app, @TreeLocalRNISelectionChanged, true);
            app.CalcMaisProx = uibutton(app.GridLayout6, 'push');
            app.CalcMaisProx.ButtonPushedFcn = createCallbackFcn(app, @CalcMaisProxButtonPushed, true);
            app.CalcMaisProx.Text = 'Calcular mais próximas';
            app.CalculadoraTab = uitab(app.TabGroup);
            app.CalculadoraTab.Title = 'Calculadora';
            app.GridLayout2 = uigridlayout(app.CalculadoraTab);
            app.ListBox_GR_UO = uilistbox(app.GridLayout2);
            app.ListBox_GR_UO.Items = {'GR01', 'GR02', 'GR03', 'GR04', 'GR05', 'GR06', 'GR07', 'GR08', 'GR09', 'GR10', 'GR11', 'UO001', 'UO021', 'UO031', 'UO061', 'UO062', 'UO071', 'UO072', 'UO073', 'UO081', 'UO091', 'UO092', 'UO101', 'UO111', 'UO112', 'UO113'};
            app.ListBox_GR_UO.Multiselect = 'on';
            app.ListBox_GR_UO.ValueChangedFcn = createCallbackFcn(app, @ListBox_GR_UOValueChanged, true);
            app.ListBox_GR_UO.Value = {};
            app.SelecioneasLocalidadesdesejadasLabel = uilabel(app.GridLayout2);
            app.SelecioneasLocalidadesdesejadasLabel.Text = 'Selecione a(s) Localidade(s) desejada(s):';
            app.MaioresnveisButton = uibutton(app.GridLayout2, 'push');
            app.MaioresnveisButton.ButtonPushedFcn = createCallbackFcn(app, @MaioresnveisButtonPushed, true);
            app.MaioresnveisButton.Enable = 'off';
            app.MaioresnveisButton.Text = 'Maiores níveis';
            app.DistnciamEditFieldLabel = uilabel(app.GridLayout2);
            app.DistnciamEditFieldLabel.Text = 'Distância (m):';
            app.DistEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DistEditField.Value = 200;
            app.Tree = uitree(app.GridLayout2, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.SelecioneosGRUOsdesejadosLabel = uilabel(app.GridLayout2);
            app.SelecioneosGRUOsdesejadosLabel.Text = 'Selecione o(s) GR/UO(s) desejado(s):';
            app.CadastroTab = uitab(app.TabGroup);
            app.CadastroTab.Title = 'Cadastro';
            app.GridLayout5 = uigridlayout(app.CadastroTab);
            app.HTML = uihtml(app.GridLayout5);
            app.HTML.HTMLSource = '''';
            app.MapaCheckBox = uicheckbox(app.GridLayout5);
            app.MapaCheckBox.Text = 'Mapa';
            app.TabelaCheckBox = uicheckbox(app.GridLayout5);
            app.TabelaCheckBox.Text = 'Tabela';
            app.ExibirButton = uibutton(app.GridLayout5, 'push');
            app.ExibirButton.ButtonPushedFcn = createCallbackFcn(app, @ExibirButtonPushed, true);
            app.ExibirButton.Text = 'Exibir';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.uipanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppRNI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end